classdef turtlebot3_guide_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                      matlab.ui.Figure
        menu_setup                   matlab.ui.container.Menu
        ROS_MASTER_IP                matlab.ui.container.Menu
        robots2                      matlab.ui.container.Menu
        Untitled_3                   matlab.ui.container.Menu
        uibuttongroup11              matlab.ui.container.ButtonGroup
        axes1                        matlab.ui.control.UIAxes
        axes2                        matlab.ui.control.UIAxes
        uibuttongroup1               matlab.ui.container.ButtonGroup
        checkbox13                   matlab.ui.control.CheckBox
        uibuttongroup10              matlab.ui.container.ButtonGroup
        uibuttongroup9               matlab.ui.container.ButtonGroup
        checkbox_number_inc          matlab.ui.control.CheckBox
        checkbox_alphabet_inc        matlab.ui.control.CheckBox
        uibuttongroup8               matlab.ui.container.ButtonGroup
        checkbox_experiment          matlab.ui.control.CheckBox
        checkbox_simulation          matlab.ui.control.CheckBox
        text7                        matlab.ui.control.Label
        edit_namespace_prefix        matlab.ui.control.EditField
        edit_select_robot_num        matlab.ui.control.EditField
        text2                        matlab.ui.control.Label
        pushbutton_namespace_update  matlab.ui.control.Button
        text4                        matlab.ui.control.Label
        uitable_init_position        matlab.ui.control.Table
        pushbutton_start             matlab.ui.control.Button
        text11                       matlab.ui.control.Label
        edit_master_ip               matlab.ui.control.EditField
        pushbutton_connect_ros       matlab.ui.control.Button
        listbox_log                  matlab.ui.control.ListBox
        pushbutton_log_box_clear     matlab.ui.control.Button
        uibuttongroup3               matlab.ui.container.ButtonGroup
        text5                        matlab.ui.control.Label
        listbox_log_level            matlab.ui.control.ListBox
        listbox3                     matlab.ui.control.ListBox
        listbox1                     matlab.ui.control.ListBox
        pushbutton3                  matlab.ui.control.Button
        pushbutton_log_level         matlab.ui.control.Button
        pushbutton1                  matlab.ui.control.Button
        uibuttongroup4               matlab.ui.container.ButtonGroup
        pushbutton_get_topic_run     matlab.ui.control.Button
        pushbutton_update_topics     matlab.ui.control.Button
        popupmenu_topics             matlab.ui.control.DropDown
        listbox_rostopic_box         matlab.ui.control.ListBox
    end

    
    methods (Access = private)
        function edit4_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit4 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function pushbutton_get_topic_stop_Callback(app, hObject, eventdata, handles)
            % hObject    handle to pushbutton_get_topic_stop (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            log_manager(hObject, eventdata, handles, "DEBUG", "pushbutton_get_topic_stop");
        end
        
        % Update components that require runtime configuration
        function addRuntimeConfigurations(app)
            
            % Set component properties that require runtime configuration
            app.uitable_init_position.BackgroundColor = [1 1 1;0.9412 0.9412 0.9412];
            app.uitable_init_position.ColumnFormat = {[] [] []};
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function turtlebot3_guide_OpeningFcn(app, varargin)
            % Add runtime required configuration - Added by Migration Tool
            addRuntimeConfigurations(app);
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to turtlebot3_guide (see VARARGIN)
            
            % Choose default command line output for turtlebot3_guide
            
            % user code
            axes(handles.axes2);
            imshow('img/turtlebot3_with_logo.png');
            
            % addpath("utils/");
            % addpath("img/");
            % addpath("linker/");
            addpath(genpath(""));
            handles.output = hObject;
            handles.log_level = "DEBUG";
            handles.robot_position = [];
            % handles.app.robot_num = 1;
            % handles.app.robot_position = [];
            % handles.app.task_type = [];
            % handles.app.robot_namespace = [];
            % handles.app.robot_namespace_increase = "";
            guidata(hObject, handles);
            
            delete_timer();
            handles.timer1000 = timer(...
                'ExecutionMode', 'fixedRate', ...       % Run timer repeatedly.
                'Period', 1, ...                        % Initial period is 1 sec.
                'TimerFcn', {@timer_1000ms,hObject}); % Specify callback function.
            handles.timer500 = timer(...
                'ExecutionMode', 'fixedRate', ...       % Run timer repeatedly.
                'Period', 0.5, ...                        % Initial period is 1 sec.
                'TimerFcn', {@timer_500ms,hObject}); % Specify callback function.
            start(handles.timer1000);
            start(handles.timer500);
            
            
            guidata(hObject, handles);
        end

        % Menu selected function: ROS_MASTER_IP
        function ROS_MASTER_IP_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to ROS_MASTER_IP (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            log_manager(hObject, eventdata, handles, "DEBUG", "setup_basic");
        end

        % Menu selected function: Untitled_3
        function Untitled_3_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Untitled_3 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            log_manager(hObject, eventdata, handles, "DEBUG", "Untitled_3");
        end

        % Value changed function: checkbox_alphabet_inc
        function checkbox_alphabet_inc_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to checkbox_alphabet_inc (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of checkbox_alphabet_inc
            checkboxstate = get(hObject, 'Value')
            if checkboxstate == 1
               handles.app.robot_namespace_increase = "alphabet";
            end
            if get(handles.checkbox_number_inc, 'Value') == 1
                set(handles.checkbox_number_inc, 'Value', 0) ;
            end
            guidata(hObject, handles);
        end

        % Value changed function: checkbox_experiment
        function checkbox_experiment_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to checkbox_experiment (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            checkboxstate = get(hObject, 'Value');
            if checkboxstate == 1
                handles.app.task_type = "experiment";
            end
            if get(handles.checkbox_simulation, 'Value') == 1
                set(handles.checkbox_simulation, 'Value', 0) ;
            end
            
            guidata(hObject, handles);
        end

        % Value changed function: checkbox_number_inc
        function checkbox_number_inc_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to checkbox_number_inc (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of checkbox_number_inc
            checkboxstate = get(hObject, 'Value')
            if checkboxstate == 1
               handles.app.robot_namespace_increase = "number";
            end
            if get(handles.checkbox_alphabet_inc, 'Value') == 1
                set(handles.checkbox_alphabet_inc, 'Value', 0) ;
            end
            guidata(hObject, handles);
        end

        % Value changed function: checkbox_simulation
        function checkbox_simulation_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to checkbox_simulation (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            checkboxstate = get(hObject, 'Value');
            if checkboxstate == 1
                handles.app.task_type = "simulation";
            end
            if get(handles.checkbox_experiment, 'Value') == 1
                set(handles.checkbox_experiment, 'Value', 0) ;
            end
            
            guidata(hObject, handles);
        end

        % Value changed function: edit_namespace_prefix
        function edit_namespace_prefix_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_namespace_prefix (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_namespace_prefix as text
            %        str2double(get(hObject,'String')) returns contents of edit_namespace_prefix as a double
            string = get(hObject, 'String');
            handles.app.robot_namespace_prefix = convertCharsToStrings(string);
            guidata(hObject, handles);
        end

        % Value changed function: edit_select_robot_num
        function edit_select_robot_num_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_select_robot_num (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_select_robot_num as text
            %        str2double(get(hObject,'String')) returns contents of edit_select_robot_num as a double
            log_manager(hObject, eventdata, handles, "DEBUG", "edit_select_robot_num");
            string = get(handles.edit_select_robot_num, 'String');
            handles.app.robot_num = str2double(string);
            
            handles.app.robot_position = zeros(2,handles.app.robot_num);
            
            guidata(hObject, handles);
        end

        % Close request function: figure1
        function figure1_CloseRequestFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to figure1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: delete(hObject) closes the figure
            delete_timer();
            delete(hObject);
        end

        % Value changed function: listbox3
        function listbox3_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to listbox3 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns listbox3 contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from listbox3
            log_manager(hObject, eventdata, handles, "DEBUG", "listbox3");
        end

        % Value changed function: listbox_log
        function listbox_log_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to listbox_log (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns listbox_log contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from listbox_log
            log_manager(hObject, eventdata, handles, "DEBUG", "listbox_log");
        end

        % Value changed function: listbox_log_level
        function listbox_log_level_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to listbox_log_level (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            log_manager(hObject, eventdata, handles, "DEBUG", "listbox_log_level");
        end

        % Menu selected function: menu_setup
        function menu_setup_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to menu_setup (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            log_manager(hObject, eventdata, handles, "DEBUG", "menu_setup");
        end

        % Button pushed function: pushbutton_connect_ros
        function pushbutton_connect_ros_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton_connect_ros (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            addpath('../matlab/utils/');
            ip = get(handles.edit_master_ip, 'String');
            ip = convertCharsToStrings(ip);
            ros_init(ip);
        end

        % Button pushed function: pushbutton_get_topic_run
        function pushbutton_get_topic_run_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton_get_topic_run (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            log_manager(hObject, eventdata, handles, "DEBUG", "pushbutton_get_topic_run");
            id = get(handles.popupmenu_topics, 'Value');
            topics = get(handles.popupmenu_topics, 'String');
            topic = topics(id); topic = convertCharsToStrings(topic);
            sub = rossubscriber(topic); msg = receive(sub, 1000);
            info = showdetails(msg);
            set(handles.listbox_rostopic_box, 'String', info);
        end

        % Button pushed function: pushbutton_log_box_clear
        function pushbutton_log_box_clear_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton_log_box_clear (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            set(handles.listbox_log, 'String', []);
            log_manager(hObject, eventdata, handles, "DEBUG", "pushbutton_log_box_clear");
        end

        % Button pushed function: pushbutton_log_level
        function pushbutton_log_level_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton_log_level (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            axes(handles.axes1);
            % x = 1:0.01:10;
            % y = sin(x);
            % plot(x,y);
            id = get(handles.listbox_log_level,'Value');
            item = get(handles.listbox_log_level, 'String');
            set_value = item(id);
            handles.log_level = set_value;
            log_manager(hObject, eventdata, handles, "INFO", strcat("The log level is setted: ", set_value ));
            guidata(hObject, handles);
        end

        % Button pushed function: pushbutton_namespace_update
        function pushbutton_namespace_update_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton_namespace_update (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % disp(handles.app)
            data = get(handles.uitable_init_position, 'Data');
            handles.app.robot_namespace = cell(handles.app.robot_num, 1);
            for i = 1 : handles.app.robot_num
                if handles.app.robot_namespace_increase == "number"
                    handles.app.robot_namespace{i} = (strcat(handles.app.robot_namespace_prefix, string(i)));
                else
                    handles.app.robot_namespace{i} = (strcat(handles.app.robot_namespace_prefix, (char(97-1+i))));
                end
            %     data{i,4} = handles.app.robot_namespace{i};
                log_manager(hObject, eventdata, handles, "INFO", handles.app.robot_namespace{i});
            
            end
            guidata(hObject, handles);
        end

        % Button pushed function: pushbutton_start
        function pushbutton_start_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton_start (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            log_manager(hObject, eventdata, handles, "DEBUG", "start button");
        end

        % Button pushed function: pushbutton_update_topics
        function pushbutton_update_topics_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton_update_topics (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            log_manager(hObject, eventdata, handles, "DEBUG", "pushbutton_update_topics");
            topiclist = rostopic("list");
            top = "topics...";
            topiclist = [top;topiclist];
            set(handles.popupmenu_topics, 'String', topiclist);
        end

        % Menu selected function: robots2
        function robots2_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to robots2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            log_manager(hObject, eventdata, handles, "DEBUG", "robots2");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'turtlebot3_guide';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1_CloseRequestFcn, true);
            app.figure1.Tag = 'figure1';
            app.menu_setup = uimenu(app.figure1);
            app.menu_setup.MenuSelectedFcn = createCallbackFcn(app, @menu_setup_Callback, true);
            app.menu_setup.Text = 'setup';
            app.menu_setup.Tag = 'menu_setup';
            app.ROS_MASTER_IP = uimenu(app.menu_setup);
            app.ROS_MASTER_IP.MenuSelectedFcn = createCallbackFcn(app, @ROS_MASTER_IP_Callback, true);
            app.ROS_MASTER_IP.Text = 'ROS_MASTER_IP';
            app.ROS_MASTER_IP.Tag = 'ROS_MASTER_IP';
            app.robots2 = uimenu(app.figure1);
            app.robots2.MenuSelectedFcn = createCallbackFcn(app, @robots2_Callback, true);
            app.robots2.Text = 'robots2';
            app.robots2.Tag = 'robots2';
            app.Untitled_3 = uimenu(app.figure1);
            app.Untitled_3.MenuSelectedFcn = createCallbackFcn(app, @Untitled_3_Callback, true);
            app.Untitled_3.Text = 'Untitled 3';
            app.Untitled_3.Tag = 'Untitled_3';
            app.uibuttongroup11 = uibuttongroup(app.figure1);
            app.uibuttongroup11.Title = 'ROS';
            app.uibuttongroup11.Tag = 'uibuttongroup11';
            app.axes1 = uiaxes(app.uibuttongroup11);
            app.axes1.Tag = 'axes1';
            app.axes2 = uiaxes(app.uibuttongroup11);
            app.axes2.Tag = 'axes2';
            app.uibuttongroup1 = uibuttongroup(app.uibuttongroup11);
            app.uibuttongroup1.Title = 'Setting box';
            app.uibuttongroup1.Tag = 'uibuttongroup1';
            app.checkbox13 = uicheckbox(app.uibuttongroup1);
            app.checkbox13.Tag = 'checkbox13';
            app.checkbox13.Text = 'generate namespace manually';
            app.checkbox13.Value = true;
            app.uibuttongroup10 = uibuttongroup(app.uibuttongroup1);
            app.uibuttongroup10.Title = 'auto generate namespace';
            app.uibuttongroup10.Tag = 'uibuttongroup10';
            app.uibuttongroup9 = uibuttongroup(app.uibuttongroup10);
            app.uibuttongroup9.Title = 'how to increase namespace';
            app.uibuttongroup9.Tag = 'uibuttongroup9';
            app.checkbox_number_inc = uicheckbox(app.uibuttongroup9);
            app.checkbox_number_inc.ValueChangedFcn = createCallbackFcn(app, @checkbox_number_inc_Callback, true);
            app.checkbox_number_inc.Tag = 'checkbox_number_inc';
            app.checkbox_number_inc.Text = 'number(1,2,3,...)';
            app.checkbox_alphabet_inc = uicheckbox(app.uibuttongroup9);
            app.checkbox_alphabet_inc.ValueChangedFcn = createCallbackFcn(app, @checkbox_alphabet_inc_Callback, true);
            app.checkbox_alphabet_inc.Tag = 'checkbox_alphabet_inc';
            app.checkbox_alphabet_inc.Text = 'alphabet(a,b,c,...)';
            app.checkbox_alphabet_inc.Value = true;
            app.uibuttongroup8 = uibuttongroup(app.uibuttongroup10);
            app.uibuttongroup8.Title = 'type';
            app.uibuttongroup8.Tag = 'uibuttongroup8';
            app.checkbox_experiment = uicheckbox(app.uibuttongroup8);
            app.checkbox_experiment.ValueChangedFcn = createCallbackFcn(app, @checkbox_experiment_Callback, true);
            app.checkbox_experiment.Tag = 'checkbox_experiment';
            app.checkbox_experiment.Text = 'experiment';
            app.checkbox_simulation = uicheckbox(app.uibuttongroup8);
            app.checkbox_simulation.ValueChangedFcn = createCallbackFcn(app, @checkbox_simulation_Callback, true);
            app.checkbox_simulation.Tag = 'checkbox_simulation';
            app.checkbox_simulation.Text = 'simulation';
            app.checkbox_simulation.Value = true;
            app.text7 = uilabel(app.uibuttongroup10);
            app.text7.Tag = 'text7';
            app.text7.Text = 'namespace_prefix';
            app.edit_namespace_prefix = uieditfield(app.uibuttongroup10, 'text');
            app.edit_namespace_prefix.ValueChangedFcn = createCallbackFcn(app, @edit_namespace_prefix_Callback, true);
            app.edit_namespace_prefix.Tag = 'edit_namespace_prefix';
            app.edit_namespace_prefix.Value = 'tb3';
            app.edit_select_robot_num = uieditfield(app.uibuttongroup10, 'text');
            app.edit_select_robot_num.ValueChangedFcn = createCallbackFcn(app, @edit_select_robot_num_Callback, true);
            app.edit_select_robot_num.Tag = 'edit_select_robot_num';
            app.edit_select_robot_num.Value = '1';
            app.text2 = uilabel(app.uibuttongroup10);
            app.text2.Tag = 'text2';
            app.text2.Text = 'robot num';
            app.pushbutton_namespace_update = uibutton(app.uibuttongroup10, 'push');
            app.pushbutton_namespace_update.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_namespace_update_Callback, true);
            app.pushbutton_namespace_update.Tag = 'pushbutton_namespace_update';
            app.pushbutton_namespace_update.Text = 'update';
            app.text4 = uilabel(app.uibuttongroup1);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Init position';
            app.uitable_init_position = uitable(app.uibuttongroup1);
            app.uitable_init_position.ColumnName = {'x-axis(m)'; 'y-axis(m)'; 'yaw(rad)'};
            app.uitable_init_position.RowName = {'1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'; '11'; '12'; '13'; '14'; '15'; '16'; '17'; '18'; '19'; '20'; '21'; '22'; '23'; '24'; '25'; '26'; '27'; '28'; '29'; '30'; '31'; '32'; '33'};
            app.uitable_init_position.ColumnEditable = [true true true];
            app.uitable_init_position.Tag = 'uitable_init_position';
            app.pushbutton_start = uibutton(app.uibuttongroup1, 'push');
            app.pushbutton_start.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_start_Callback, true);
            app.pushbutton_start.Tag = 'pushbutton_start';
            app.pushbutton_start.Text = 'START!';
            app.text11 = uilabel(app.uibuttongroup1);
            app.text11.Tag = 'text11';
            app.text11.Text = 'master ip';
            app.edit_master_ip = uieditfield(app.uibuttongroup1, 'text');
            app.edit_master_ip.Tag = 'edit_master_ip';
            app.edit_master_ip.Value = '192.168.0.18';
            app.pushbutton_connect_ros = uibutton(app.uibuttongroup1, 'push');
            app.pushbutton_connect_ros.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_connect_ros_Callback, true);
            app.pushbutton_connect_ros.Tag = 'pushbutton_connect_ros';
            app.pushbutton_connect_ros.Text = 'connect to ROS';
            app.listbox_log = uilistbox(app.uibuttongroup11);
            app.listbox_log.Items = {'Log'};
            app.listbox_log.ValueChangedFcn = createCallbackFcn(app, @listbox_log_Callback, true);
            app.listbox_log.Tag = 'listbox_log';
            app.listbox_log.Value = 'Log';
            app.pushbutton_log_box_clear = uibutton(app.uibuttongroup11, 'push');
            app.pushbutton_log_box_clear.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_log_box_clear_Callback, true);
            app.pushbutton_log_box_clear.Tag = 'pushbutton_log_box_clear';
            app.pushbutton_log_box_clear.Text = 'clear';
            app.uibuttongroup3 = uibuttongroup(app.uibuttongroup11);
            app.uibuttongroup3.Title = 'Setting level';
            app.uibuttongroup3.Tag = 'uibuttongroup3';
            app.text5 = uilabel(app.uibuttongroup3);
            app.text5.Tag = 'text5';
            app.text5.Text = 'log level';
            app.listbox_log_level = uilistbox(app.uibuttongroup3);
            app.listbox_log_level.Items = {'DEBUG', 'ERROR', 'WARNNING'};
            app.listbox_log_level.ValueChangedFcn = createCallbackFcn(app, @listbox_log_level_Callback, true);
            app.listbox_log_level.Tag = 'listbox_log_level';
            app.listbox_log_level.Value = 'DEBUG';
            app.listbox3 = uilistbox(app.uibuttongroup3);
            app.listbox3.Items = {'목록 상자', '1', '2', '3', '4'};
            app.listbox3.ValueChangedFcn = createCallbackFcn(app, @listbox3_Callback, true);
            app.listbox3.Tag = 'listbox3';
            app.listbox3.Value = '목록 상자';
            app.listbox1 = uilistbox(app.uibuttongroup3);
            app.listbox1.Items = {'목록 상자', '1', '2', '3', '4'};
            app.listbox1.Tag = 'listbox1';
            app.listbox1.Value = '목록 상자';
            app.pushbutton3 = uibutton(app.uibuttongroup3, 'push');
            app.pushbutton3.Tag = 'pushbutton3';
            app.pushbutton3.Text = '푸시 버튼';
            app.pushbutton_log_level = uibutton(app.uibuttongroup3, 'push');
            app.pushbutton_log_level.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_log_level_Callback, true);
            app.pushbutton_log_level.Tag = 'pushbutton_log_level';
            app.pushbutton_log_level.Text = 'set';
            app.pushbutton1 = uibutton(app.uibuttongroup3, 'push');
            app.pushbutton1.Tag = 'pushbutton1';
            app.pushbutton1.Text = '푸시 버튼';
            app.uibuttongroup4 = uibuttongroup(app.uibuttongroup11);
            app.uibuttongroup4.Title = 'ROS topic viewer';
            app.uibuttongroup4.Tag = 'uibuttongroup4';
            app.pushbutton_get_topic_run = uibutton(app.uibuttongroup4, 'push');
            app.pushbutton_get_topic_run.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_get_topic_run_Callback, true);
            app.pushbutton_get_topic_run.Tag = 'pushbutton_get_topic_run';
            app.pushbutton_get_topic_run.Text = 'get';
            app.pushbutton_update_topics = uibutton(app.uibuttongroup4, 'push');
            app.pushbutton_update_topics.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_update_topics_Callback, true);
            app.pushbutton_update_topics.Tag = 'pushbutton_update_topics';
            app.pushbutton_update_topics.Text = 'update topics';
            app.popupmenu_topics = uidropdown(app.uibuttongroup4);
            app.popupmenu_topics.Items = {'topics...'};
            app.popupmenu_topics.Tag = 'popupmenu_topics';
            app.popupmenu_topics.Value = 'topics...';
            app.listbox_rostopic_box = uilistbox(app.uibuttongroup4);
            app.listbox_rostopic_box.Items = {'rostopic'};
            app.listbox_rostopic_box.Tag = 'listbox_rostopic_box';
            app.listbox_rostopic_box.Value = 'rostopic';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = turtlebot3_guide_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)turtlebot3_guide_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end